classdef GUISeniorDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RefreshButton                 matlab.ui.control.Button
        RefreshButton_2               matlab.ui.control.Button
        RefreshButton_3               matlab.ui.control.Button
        RefreshButton_4               matlab.ui.control.Button
        LightSwitchLabel              matlab.ui.control.Label
        LightSwitch                   matlab.ui.control.Switch
        HowoftentofeedhoursEditFieldLabel  matlab.ui.control.Label
        HowoftentofeedhoursEditField  matlab.ui.control.NumericEditField
        SubmitFeedTimeButton          matlab.ui.control.Button
        LastpHTextAreaLabel           matlab.ui.control.Label
        LastpHTextArea                matlab.ui.control.TextArea
        LastTurbidityTextAreaLabel    matlab.ui.control.Label
        LastTurbidityTextArea         matlab.ui.control.TextArea
        LastTemperatureTextAreaLabel  matlab.ui.control.Label
        LastTemperatureTextArea       matlab.ui.control.TextArea
        LastFedTextAreaLabel          matlab.ui.control.Label
        LastFedTextArea               matlab.ui.control.TextArea
        AutonomousSystemSwitchLabel   matlab.ui.control.Label
        AutonomousSystemSwitch        matlab.ui.control.Switch
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lastfedtime 
        lasttemptime 
        lastphtime 
        lastturbtime 
        settime 
        time  
        temptime = 1 
        turbtime = 1 
        temp = zeros(24,1) 
        turb = zeros(24,1) 
        ard = arduino('COM5', 'UNO', 'Libraries', {'Servo','PaulStoffregen/OneWire'})
        t0 = datetime('now') 
        ttime
        timer = 1
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HowoftentofeedhoursEditField
        function HowoftentofeedhoursEditFieldValueChanged(app, event)
            value = app.HowoftentofeedhoursEditField.Value;
            %change the number of hours the fish are fed
            app.settime = value; 
        end

        % Button pushed function: RefreshButton_4
        function RefreshButton_4Pushed(app, event)
            clear s
            s = servo(app.ard, 'D4', 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);

            for i = 0:1:1
                for angle = 1:-1:0
                    writePosition(s, angle);
                    current_pos = readPosition(s);
                    current_pos = current_pos*180;
                    %fprintf('Current motor position is %d degrees\n', current_pos);
                    pause(0.5);
                end
            end
            
            fprintf('Done with the feeding \n');
            
            timestr = clock;
            app.lastfedtime = datestr(timestr);
            app.LastFedTextArea.Value = app.lastfedtime;
            clear s;
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            i = app.temptime;
            app.temptime = app.temptime + 1;
            
            if (i > 25)
                app.time = 1;
                i = 1;
                app.temptime = zeros(24, 1);
            end
            
            sensor = addon(app.ard, 'PaulStoffregen/OneWire', 'D10');
            addr = sensor.AvailableAddresses{1};
            reset(sensor);
            write(sensor, addr, hex2dec('44'), true);
            pause(1);
            reset(sensor);
            write(sensor, addr, hex2dec('BE')); % read command - 'BE'
            data = read(sensor, addr, 9);
            crc = data(9);
            sprintf('Data = %x %x %x %x %x %x %x %x CRC = %x\n', ...
    data(1), data(2), data(3), data(4), data(5), data(6), data(7), ...
    data(8), crc)
            if ~checkCRC(sensor, data(1:8), crc, 'crc8')
                error('Invalid data read.');
            end
            raw = bitshift(data(2),8)+data(1);
            cfg = bitshift(bitand(data(5), hex2dec('60')), -5);
            switch cfg
                case bin2dec('00') % 9-bit resolution, 93.75 ms conversion time
                    raw = bitand(raw, hex2dec('fff8'));
                case bin2dec('01') % 10-bit resolution, 187.5 ms conversion time
                    raw = bitand(raw, hex2dec('fffC'));
                case bin2dec('10') % 11-bit resolution, 375 ms conversion time
                    raw = bitand(raw, hex2dec('fffE'));
                case bin2dec('11') % 12-bit resolution, 750 ms conversion time
                otherwise
                    error('Invalid resolution configuration');
           end

            % Convert temperature reading from unsigned 16-bit value to signed 16-bit.
            raw = typecast(uint16(raw), 'int16');
            % Convert to the actual floating point value since the last bit of LSB
            % represents $2^{-4}$.
            celsius = double(raw) / 16.0;
            fahrenheit = celsius * 1.8 + 32.0;
            app.temp(i,:) = fahrenheit;
            sprintf('Temperature = %.4f Celsius, %.4f Fahrenheit', celsius, app.temp(i));

            clear sensor;
            
            tt = datenum(datetime('now') - app.t0);
            app.ttime(i) = tt;
            
            j = 1;
            while app.temp(j) ~= 0
                temparr1(j) = app.temp(j);
                j = j + 1;
            end
            
            if(j == 25)
                j = 1;
                for k  = 1:1:25
                    temparr(k) = 0;
                end
            end
            
            lsttemptime = clock;
            app.lasttemptime = datestr(lsttemptime);
            app.LastTemperatureTextArea.Value = app.lasttemptime;
            
            plot (app.UIAxes, app.ttime, temparr1); %app.temp
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            configurePin(app.ard, 'A0', 'analoginput');
            
            i = app.turbtime;
            app.turbtime = app.turbtime + 1;
            
            if (i > 25)
                app.turbtime = 1;
                i = 1;
                app.turb = zeros(24,1);
            end
            
            t = readVoltage(app.ard, 'A0');
            app.turb(i, :) = t;
            tt = datenum(datetime('now') - app.t0);
            app.time(i) = tt;
            
            j = 1;
            while(j < 25)
                %fprintf('voltage is %d\n', app.turb(j,:));
                j = j + 1;
            end 
             
            j = 1;
            while app.turb(j) ~= 0
                temparr(j) = app.turb(j);
                j = j + 1;
            end
            
            if(j == 25)
                j = 1;
                 for k = 1:1:25
                     temparr(k) = 0;
                 end
            end
            
            timestr = clock;
            app.lastturbtime = datestr(timestr);
            app.LastTurbidityTextArea.Value = app.lastturbtime;
          
            plot(app.UIAxes3, app.time, temparr); %app.turb
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.lastphtime = datestr(now,'HH:MM:SS.FFF'); %Sets the date
            %plot 
        end

        % Value changed function: AutonomousSystemSwitch
        function AutonomousSystemSwitchValueChanged(app, event)
            value = app.AutonomousSystemSwitch.Value;
            
            switch value 
                case 'On'
                    %while(1)
                    sens_count = 0;
                    feed_count = 0;
                    while(app.timer > 0)
                        
                       delay1 = 20;
                       input_feed = app.settime*60; %3600 for hours
                       %fprintf("input feed is %d\n", input_feed);

                       pause(1);
                       fprintf('.\n');
                       sens_count = sens_count + 1;
                       feed_count = feed_count + 1;
                       
                       val = app.LightSwitch.Value;
                       if(val == "On")
                            writePWMVoltage(app.ard, 'D6', 3.3); %G 
                            writePWMVoltage(app.ard, 'D5', 3.3); %R
                            writePWMVoltage(app.ard, 'D3', 3.3); %B
                       elseif (val == "Off")
                           writePWMVoltage(app.ard, 'D6', 0); %G 
                           writePWMVoltage(app.ard, 'D5', 0); %R
                           writePWMVoltage(app.ard, 'D3', 0); %B
                       end

                       if(sens_count == delay1)
                        app.timer = app.timer + delay1;

                        value = app.AutonomousSystemSwitch.Value;
                        if(value == "Off")
                            break;
                        end
                        
                        %Resets the tmeperature every day
                        if(app.time > 25)
                            app.time = 1;
                            app.turb = zeros(24,1);
                        end
                        
                        if(app.ttime > 25)
                            app.ttime = 1;
                            app.temp = zeros(24,1);
                        end

                        %Temperature Sensor
                        i = app.temptime;
                        
                        sensor = addon(app.ard, 'PaulStoffregen/OneWire', 'D10');
                        addr = sensor.AvailableAddresses{1};
                        reset(sensor);
                        write(sensor, addr, hex2dec('44'), true);
                        pause(1);
                        reset(sensor);
                        write(sensor, addr, hex2dec('BE')); % read command - 'BE'
                        data = read(sensor, addr, 9);
                        crc = data(9);
                        sprintf('Data = %x %x %x %x %x %x %x %x CRC = %x\n', ...
    data(1), data(2), data(3), data(4), data(5), data(6), data(7), ...
    data(8), crc)
                        if ~checkCRC(sensor, data(1:8), crc, 'crc8')
                            error('Invalid data read.');
                        end
                        raw = bitshift(data(2),8)+data(1);
                        cfg = bitshift(bitand(data(5), hex2dec('60')), -5);
                        switch cfg
                            case bin2dec('00') % 9-bit resolution, 93.75 ms conversion time
                                raw = bitand(raw, hex2dec('fff8'));
                            case bin2dec('01') % 10-bit resolution, 187.5 ms conversion time
                                raw = bitand(raw, hex2dec('fffC'));
                            case bin2dec('10') % 11-bit resolution, 375 ms conversion time
                                raw = bitand(raw, hex2dec('fffE'));
                            case bin2dec('11') % 12-bit resolution, 750 ms conversion time
                            otherwise
                                error('Invalid resolution configuration');
                       end
            
                        % Convert temperature reading from unsigned 16-bit value to signed 16-bit.
                        raw = typecast(uint16(raw), 'int16');
                        % Convert to the actual floating point value since the last bit of LSB
                        % represents $2^{-4}$.
                        celsius = double(raw) / 16.0;
                        fahrenheit = celsius * 1.8 + 32.0;
                        app.temp(i,:) = fahrenheit;
                        sprintf('Temperature = %.4f Celsius, %.4f Fahrenheit', celsius, app.temp(i));
            
                        clear sensor;
                        
                        tt = datenum(datetime('now') - app.t0);
                        app.ttime(i) = tt;

                        j = 1;
                        while app.temp(j) ~= 0
                            temparr1(j) = app.temp(j);
                            j = j + 1;
                        end
                        
                        if(j == 25)
                            j = 1;
                            for k  = 1:1:25
                                temparr(k) = 0;
                            end
                        end
                        
                        lsttemptime = clock;
                        app.lasttemptime = datestr(lsttemptime);
                        app.LastTemperatureTextArea.Value = app.lasttemptime;
                        
                        plot (app.UIAxes, app.ttime, temparr1); %app.temp
                      
                        
                        %turbidity Sensor 
                        configurePin(app.ard, 'A0', 'analoginput');
                        
                        i1 = app.turbtime;
                        
                        t = readVoltage(app.ard, 'A0');
                        %fprintf(' t is %d\n', t);
                        app.turb(i1, :) = t;
                        
                        tt = datenum(datetime('now') - app.t0);
                        app.time(i1) = tt;
            
                        j1 = 1;
                        while(j1 < 25)
                            %fprintf('voltage is %d\n', app.turb(j1,:));
                            j1 = j1 + 1;
                        end 
                        
                        if(j1 == 25)
                            j1 = 1;
                            for k1 = 1:1:24
                                temparr(k1) = 0;
                            end
                        end
                        
                        j1 = 1;
                        while (app.turb(j1) ~= 0 && j1 < 25)
                            temparr1(j1) = app.turb(j1);
                            j1 = j1 + 1;
                        end

                        timestr = clock;
                        app.lastturbtime = datestr(timestr);
                        app.LastTurbidityTextArea.Value = app.lastturbtime;
          
                        plot(app.UIAxes3, app.time, temparr1); %app.turb
                        
                        app.temptime = app.temptime + 1;
                        app.turbtime = app.turbtime + 1;
                        sens_count = 0;
                       end 
                       if(input_feed == feed_count)
                               clear s;
                                s = servo(app.ard, 'D4', 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);
                                    time_to_feed = (app.settime*60); %3600
                                    app.timer = app.timer + time_to_feed;

                                    %Feed 
                                    for i = 0:1:1
                                        for angle = 1:-1:0
                                            writePosition(s, angle);
                                            current_pos = readPosition(s);
                                            current_pos = current_pos*180;
                                            %fprintf('Current motor position is %d degrees\n', current_pos);
                                            pause(0.5);
                                        end
                                    end
                                    fprintf('Done with the feeding \n');
                                    timestr = clock;
                                    app.lastfedtime = datestr(timestr);
                                    app.LastFedTextArea.Value = app.lastfedtime;
                                %end
                                clear s;
                                feed_count = 0;
                           end
                    end
                case 'Off'
                    while(app.timer > 0)
                        pause(1);
                        app.timer = app.timer + 1;
                        
                        val = app.LightSwitch.Value;
                           if(val == "On")
                                writePWMVoltage(app.ard, 'D6', 3.3); %G 
                                writePWMVoltage(app.ard, 'D5', 3.3); %R
                                writePWMVoltage(app.ard, 'D3', 3.3); %B
                           elseif (val == "Off")
                               writePWMVoltage(app.ard, 'D6', 0); %G 
                               writePWMVoltage(app.ard, 'D5', 0); %R
                               writePWMVoltage(app.ard, 'D3', 0); %B
                           end
    
                            value = app.AutonomousSystemSwitch.Value;
                            if(value == "On")
                                break;
                            end
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton_2 = uibutton(app.UIFigure, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.RefreshButton_3 = uibutton(app.UIFigure, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Text = 'Refresh';
            app.RefreshButton_4 = uibutton(app.UIFigure, 'push');
            app.RefreshButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_4Pushed, true);
            app.RefreshButton_4.Text = 'Refresh';
            app.LightSwitchLabel = uilabel(app.UIFigure);
            app.LightSwitchLabel.Text = 'Light Switch';
            app.LightSwitch = uiswitch(app.UIFigure, 'slider');
            app.HowoftentofeedhoursEditFieldLabel = uilabel(app.UIFigure);
            app.HowoftentofeedhoursEditFieldLabel.Text = 'How often to feed? (hours)';
            app.HowoftentofeedhoursEditField = uieditfield(app.UIFigure, 'numeric');
            app.HowoftentofeedhoursEditField.ValueChangedFcn = createCallbackFcn(app, @HowoftentofeedhoursEditFieldValueChanged, true);
            app.SubmitFeedTimeButton = uibutton(app.UIFigure, 'push');
            app.SubmitFeedTimeButton.Text = 'Submit Feed Time';
            app.LastpHTextAreaLabel = uilabel(app.UIFigure);
            app.LastpHTextAreaLabel.Text = 'Last pH';
            app.LastpHTextArea = uitextarea(app.UIFigure);
            app.LastTurbidityTextAreaLabel = uilabel(app.UIFigure);
            app.LastTurbidityTextAreaLabel.Text = 'Last Turbidity';
            app.LastTurbidityTextArea = uitextarea(app.UIFigure);
            app.LastTemperatureTextAreaLabel = uilabel(app.UIFigure);
            app.LastTemperatureTextAreaLabel.Text = 'Last Temperature';
            app.LastTemperatureTextArea = uitextarea(app.UIFigure);
            app.LastFedTextAreaLabel = uilabel(app.UIFigure);
            app.LastFedTextAreaLabel.Text = 'Last Fed';
            app.LastFedTextArea = uitextarea(app.UIFigure);
            app.AutonomousSystemSwitchLabel = uilabel(app.UIFigure);
            app.AutonomousSystemSwitchLabel.Text = 'Autonomous System';
            app.AutonomousSystemSwitch = uiswitch(app.UIFigure, 'slider');
            app.AutonomousSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @AutonomousSystemSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'F')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'pH Balance')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'pH')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14];
            app.UIAxes2.YTickLabel = {'0'; '10'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Turbidity')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Turbidity')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUISeniorDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end